classdef logAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Button_Expand             matlab.ui.control.Button
        Button_Rotate             matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        GraphsTab                 matlab.ui.container.Tab
        Data1DropDown             matlab.ui.control.DropDown
        Data1DropDownLabel        matlab.ui.control.Label
        Data3DropDown             matlab.ui.control.DropDown
        Data3DropDownLabel        matlab.ui.control.Label
        Data2DropDown             matlab.ui.control.DropDown
        Data2DropDownLabel        matlab.ui.control.Label
        UIAxes1                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        AnalysisTab               matlab.ui.container.Tab
        ylimmatchSwitch           matlab.ui.control.Switch
        ylimmatchSwitchLabel      matlab.ui.control.Label
        detrendRSwitch            matlab.ui.control.Switch
        detrendSwitch_2Label      matlab.ui.control.Label
        normalizeRSwitch          matlab.ui.control.Switch
        normalizeSwitch_2Label    matlab.ui.control.Label
        detrendLSwitch            matlab.ui.control.Switch
        detrendSwitchLabel        matlab.ui.control.Label
        normalizeLSwitch          matlab.ui.control.Switch
        normalizeSwitchLabel      matlab.ui.control.Label
        DataAnalysisLDropDown     matlab.ui.control.DropDown
        smoothLDropDown           matlab.ui.control.DropDown
        FunctionLabel             matlab.ui.control.Label
        windowLSpinner            matlab.ui.control.Spinner
        windowSpinnerLabel        matlab.ui.control.Label
        DataAnalysisRDropDown     matlab.ui.control.DropDown
        smoothRDropDown           matlab.ui.control.DropDown
        windowRSpinner            matlab.ui.control.Spinner
        windowSpinner_2Label      matlab.ui.control.Label
        smoothDropDown_2Label     matlab.ui.control.Label
        DataDropDownLabel_2       matlab.ui.control.Label
        DataLabel                 matlab.ui.control.Label
        UIAxesAnalysis            matlab.ui.control.UIAxes
        ParameterTab              matlab.ui.container.Tab
        TextArea_2                matlab.ui.control.TextArea
        consoleTab                matlab.ui.container.Tab
        TextArea                  matlab.ui.control.TextArea
        SettingTab                matlab.ui.container.Tab
        Plotflag3DropDown         matlab.ui.control.DropDown
        Plotflag3DropDownLabel    matlab.ui.control.Label
        Plotflag3enableCheckBox   matlab.ui.control.CheckBox
        Plotflag2DropDown         matlab.ui.control.DropDown
        Plotflag2DropDownLabel    matlab.ui.control.Label
        Plotflag2enableCheckBox   matlab.ui.control.CheckBox
        Plotflag1DropDown         matlab.ui.control.DropDown
        PlotflagLabel             matlab.ui.control.Label
        Plotflag1enableCheckBox   matlab.ui.control.CheckBox
        diffCheckBox              matlab.ui.control.CheckBox
        ShortCutwindowLabel       matlab.ui.control.Label
        ShortCutwindowSlider      matlab.ui.control.Slider
        ShortCutplotSwitch        matlab.ui.control.Switch
        ShortCutplotSwitchLabel   matlab.ui.control.Label
        SettingCSVfilepathLabel   matlab.ui.control.Label
        csvFilePathButton         matlab.ui.control.Button
        csvFilePathEditer         matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        SwitchDirection           matlab.ui.control.ToggleSwitch
        CourseajustgyroKnob       matlab.ui.control.Knob
        CourseajustgyroKnobLabel  matlab.ui.control.Label
        Spinner                   matlab.ui.control.Spinner
        graphPositionBotton       matlab.ui.control.Switch
        SwitchLabel               matlab.ui.control.Label
        radsCheckBox              matlab.ui.control.CheckBox
        TranslateequationsLabel   matlab.ui.control.Label
        timeEditField             matlab.ui.control.EditField
        velocitymmsLabel_2        matlab.ui.control.Label
        angularVelocityEditField  matlab.ui.control.EditField
        angularVelocitydegsLabel  matlab.ui.control.Label
        velocityEditField         matlab.ui.control.EditField
        velocitymmsLabel          matlab.ui.control.Label
        LogDropDown               matlab.ui.control.DropDown
        LogDropDownLabel          matlab.ui.control.Label
        TimesSlider               matlab.ui.control.Slider
        TimesLabel                matlab.ui.control.Label
        ReloadButton              matlab.ui.control.Button
        FolderpathLabel           matlab.ui.control.Label
        FolderButton              matlab.ui.control.Button
        Lamp                      matlab.ui.control.Lamp
        UIAxisCourse              matlab.ui.control.UIAxes
    end

    %プロパティ
    properties (Access = private)
        rootDir % ログフォルダのディレクトリ
        csvFileName = "LogViewerSetting.csv"
        textarea = {}       % テキストエリアに表示する文字列用cell配列
        log                 % ログデータ用table変数
        course              % 走行軌跡の座標配列
        shortCutCourse      % ショートカットの走行軌跡座標配列
        marker              % 現在地のプロット
        flag1               % フラグをコースにプロット
        flag2               % フラグをコースにプロット
        flag3               % フラグをコースにプロット
        xlines = cell(1,10) % X軸定数線のオブジェクト配列
        courseColorbar      % コース描画用カラーバーハンドル
        expand              % コースプロット最大化フラグ 1:最大
        rotate = 0          % コースプロットの回転フラグ 1:90° CCW回転

        time            % 時間配列
        dt              % 最初の単位時間
        velocity        % 速度配列
        angularVelocity % 角速度配列
        pulse = 60.074	% 1mmのロータリーエンコーダパルス
        kgyro = 1       % 角度補正係数
        courseSize = [  -400    800;
                        -1000   1000]
        parameter          % ログのヘッダー
    end

    % メソッド
    methods (Access = private)

        % ログデータを取得する
        function getLogdata(app)
            filename = strsplit(app.LogDropDown.Value);
			filename = filename{1};
            isSuccess = true;	% 例外発生時に後続処理を止めるためのフラグ

			try
				% ログファイル読み込みと最低限の行数チェックを例外処理で保護
                filePath = append(app.rootDir,'\\',filename,".csv");
				app.log = readtable(filePath);		  % ログファイル読み込み
                meta = readcell(filePath,"Range","A1:AAAA1");         % パラメータを取得
                colum = readcell(filePath,"Range","A2:AAAA2");         % 列名を取得 
                rowNum = size(app.log.Properties.VariableNames,2);      % 有効なヘッダ数を取得
                app.log.Properties.VariableNames = colum(1,1:rowNum);  %ヘッダを設定

                % ヘッダに含まれるパラメータを取得
                app.parameter = meta;
                if isempty(app.parameter) == 0
                    % パラメータがあるときはテキストエリアに書き込み
                    app.TextArea_2.Value = app.parameter;
                else
                    % パラメータがないときはテキストエリアをクリア
                    app.TextArea_2.Value = "";
                end
                
                if height(app.log) < 2
                    error("logAnalysis:InsufficientLogRows","ログファイルの行数が2行未満です。");
                end

                % テキストフィールドから換算式を取得
				% 時間行列[s]
				timeeq = split(app.timeEditField.Value);
				app.time = app.log.(timeeq{1});
				i = 2;
				while i <= numel(timeeq)
					if i + 1 > numel(timeeq)
						error("logAnalysis:InvalidEquation","時間換算式の演算子と値の組み合わせが不足しています。");
					end
					operand = str2double(timeeq{i + 1});
					if isnan(operand)
						error("logAnalysis:InvalidEquation","時間換算式に数値以外が指定されています: %s", timeeq{i + 1});
					end
					% 演算子を判定して掛け算・割り算を実行
					if timeeq(i) == "*"
						app.time = app.time * operand;
					elseif timeeq(i) == "/"
						app.time = app.time / operand;
					else
						error("logAnalysis:InvalidEquation","時間換算式に無効な演算子があります: %s", timeeq{i});
					end
					i = i + 2;
                end
                app.dt = diff(app.time);
                app.dt = [app.dt;app.dt(end)];

				% 速度行列[mm/s] の換算式を検証しながら計算
				velocityeq = split(app.velocityEditField.Value);
				app.velocity = app.log.(velocityeq{1});
                if app.diffCheckBox.Value == 1
                    m = diff(app.velocity);
                    n = [m;m(end)];
                    o = n ./ app.dt;

                    app.velocity = o;
                end
				i = 2;
				while i <= numel(velocityeq)
					if i + 1 > numel(velocityeq)
						error("logAnalysis:InvalidEquation","速度換算式の演算子と値の組み合わせが不足しています。");
					end
					velocityOperand = str2double(velocityeq{i + 1});
					if isnan(velocityOperand)
						error("logAnalysis:InvalidEquation","速度換算式に数値以外が指定されています: %s", velocityeq{i + 1});
					end
					% 速度換算式の演算子を判定して計算
					if velocityeq(i) == "*"
						app.velocity = app.velocity * velocityOperand;
					elseif velocityeq(i) == "/"
						app.velocity = app.velocity / velocityOperand;
					else
						error("logAnalysis:InvalidEquation","速度換算式に無効な演算子があります: %s", velocityeq{i});
					end
					i = i + 2;
				end

				% 角度速度配列[deg/s] の換算式を検証しながら計算（右旋回時にプラス）
				angularVelocityeq = split(app.angularVelocityEditField.Value);
				app.angularVelocity = app.log.(angularVelocityeq{1});
				i = 2;
                while i <= numel(angularVelocityeq)
                    if i + 1 > numel(angularVelocityeq)
                        error("logAnalysis:InvalidEquation","角速度換算式の演算子と値の組み合わせが不足しています。");
                    end
                    angularVelocityOperand = str2double(angularVelocityeq{i + 1});
                    if isnan(angularVelocityOperand)
                        error("logAnalysis:InvalidEquation","角速度換算式に数値以外が指定されています: %s", angularVelocityeq{i + 1});
                    end
                    % 角速度換算式の演算子を判定して計算
                    if angularVelocityeq(i) == "*"
                        app.angularVelocity = app.angularVelocity * angularVelocityOperand;
                    elseif angularVelocityeq(i) == "/"
                        app.angularVelocity = app.angularVelocity / angularVelocityOperand;
                    else
                        error("logAnalysis:InvalidEquation","角速度換算式に無効な演算子があります: %s", angularVelocityeq{i});
                    end
                    i = i + 2;
                end

			catch ME
				app.Lamp.Color = "red";	% 例外発生をランプで通知
				setTexttarea(app, {ME.identifier, ME.message});	% エラー内容をテキストエリアに表示
				isSuccess = false;
			end

			% 例外時は後続処理をスキップ
			if ~isSuccess
				return;
			end

			setTexttarea(app, {append(app.rootDir,'\\',app.LogDropDown.Value,'.csv'), 'ログファイル読み込み完了'})
        end
        

        % ログファイル一覧の取得
        function getLogfiles(app)
            origDir = pwd;                              % カレントディレクトリを保持
			cleanupObj = onCleanup(@() cd(origDir));    % 例外発生時も元に戻す
			try
				if ~isfolder(app.rootDir)
					error("MATLAB:cd:NonExistentFolder","The folder does not exist.");   % 存在しない場合は従来エラーを再現
				end

				fileList = dir(fullfile(app.rootDir,'*.csv'));          % cdを使わずにcsvファイル一覧を取得
				fileList = struct2table(fileList);                      % table型に変換
				fileList.name = convertCharsToStrings(fileList.name);   % String型に変換
				fileList.name = erase(fileList.name,lettersPattern | characterListPattern("."));   % 文字と.を削除
				fileList.name = str2double(fileList.name);              % double型に変換
				idx = isnan(fileList.name);     % NaNを含む行を検索
				fileList = fileList(~idx,:);    % NaNを含まない行だけを残す
				fileList = fileList(fileList.bytes ~= 0,:);    % 空ファイルを削除
				fileList = sortrows(fileList,'name','descend');     % 降順にソート
				fileList.name = string(fileList.name);              % string型に変換

				app.LogDropDown.Items = {};                         % ドロップダウンメニューを初期化

                fileSize = num2str(round(fileList.bytes/1000,0),3);    % ファイルサイズ
                fileSize = string(fileSize);
                fileDate = datetime(fileList.datenum,"ConvertFrom","datenum");  % ファイル更新日
                fileDate = string(fileDate,"yyyy/MM/dd");

				app.LogDropDown.Items = append(fileList.name,"    ",fileSize,"KB","    ",fileDate);          % ドロップダウンメニューに追加
	
				app.Lamp.Color = "green";   % ステータスランプ緑
				app.TextArea.Value = "";    % ステータスクリア
			catch ME
				app.Lamp.Color = "red";     % ステータスランプ赤
				app.TextArea.Value = {ME.identifier,ME.message};   % エラー表示
				if (strcmp(ME.identifier,'MATLAB:cd:NonExistentFolder'))
					% 指定フォルダが見つからない
					app.FolderButton.Text = "not found folder";
				end
				if (strcmp(ME.identifier,'MATLAB:string:MustBeCharCellArrayOrString'))
					% 指定フォルダにcsvファイルが無い
					app.LogDropDown.Items = "not found files";
				end
			end
        end

        % ログ変数一覧を取得
        function valnames = getLogvalues(app)
            valnames = app.log.Properties.VariableNames;
            valnames = valnames(1,2:end);
            app.Data1DropDown.Items = valnames;
            app.Data2DropDown.Items = valnames;
            app.Data3DropDown.Items = valnames;
            app.Plotflag1DropDown.Items = valnames;
            app.Plotflag2DropDown.Items = valnames;
            app.Plotflag3DropDown.Items = valnames;
            app.DataAnalysisLDropDown.Items = [{'none'} valnames];
            app.DataAnalysisRDropDown.Items = [{'none'} valnames];
        end

        % テキストエリアに文字列を表示する
        function setTexttarea(app, str)
            app.textarea = [app.textarea str];  % cell配列に文字列を追加
            app.TextArea.Value = app.textarea;  % テキストエリアに表示
            scroll(app.TextArea,'bottom');      % 最下部までスクロール
        end
        
        % コース座標の計算
        function calcCourseplot(app)
            try
                % 座標算出
                degxy = cumtrapz(app.time, app.angularVelocity .* app.kgyro);  % 角度行列[deg/s] 角速度を積算
                % コースプロット回転ボタンが押されたとき
                degxy = degxy + app.rotate; % 全体を90°加算

                if app.radsCheckBox.Value == true
                    x = cumtrapz(app.time, ( app.velocity .* sin(degxy) ));
                    y = cumtrapz(app.time, ( app.velocity .* cos(degxy) ));
                else
                    x = cumtrapz(app.time, ( app.velocity .* sind(degxy) ));
                    y = cumtrapz(app.time, ( app.velocity .* cosd(degxy) ));
                end
                % timetable変数作成
                app.course = timetable(seconds(app.time),x,y,app.velocity,'VariableNames',{'x','y','velocity'});
            catch ME
                app.Lamp.Color = "red";     % ステータスランプ赤
                % エラー表示
                for num = 1:1:length(ME.stack)
                    setTexttarea(app, {ME.stack(num,1).name,num2str(ME.stack(num,1).line)})
                end
                setTexttarea(app, {ME.identifier,ME.message})
            end
        end

        % ショートカット軌跡座標の計算
        function calcShortCutplot(app)
            try
                % windowが1未満の場合は処理を中断
                app.ShortCutwindowSlider.Value = round(app.ShortCutwindowSlider.Value);
                if app.ShortCutwindowSlider.Value < 1
                    app.Lamp.Color = "red";     % ステータスランプ赤
                    setTexttarea(app, {"windowの値が1未満です", "1以上に設定してください"});
                    return;
                end
                % 座標算出
                x = movmean(app.course.x,app.ShortCutwindowSlider.Value);
                y = movmean(app.course.y,app.ShortCutwindowSlider.Value);
                % timetable変数作成
                app.shortCutCourse = timetable(seconds(app.time),x,y,app.velocity,'VariableNames',{'x','y','velocity'});
            catch ME
                app.Lamp.Color = "red";     % ステータスランプ赤
                % エラー表示
                for num = 1:1:length(ME.stack)
                    setTexttarea(app, {ME.stack(num,1).name,num2str(ME.stack(num,1).line)})
                end
                setTexttarea(app, {ME.identifier,ME.message})
            end
        end
       
        % コース描画
        function plotCourse(app)
            try
                % 既存カラーバーがある場合は削除して重複表示を防止
                if ~isempty(app.courseColorbar) && isvalid(app.courseColorbar)
                    delete(app.courseColorbar);
                end
                % プロット
                scatter(app.UIAxisCourse,app.course,'x','y','ColorVariable','velocity','ButtonDownFcn',@(src,evnt)getTimeIndex(app,src,evnt));
                app.UIAxisCourse.XLim = [min(app.course.x)-100 max(app.course.x)+100];
                app.UIAxisCourse.YLim = [min(app.course.y)-100 max(app.course.y)+100];
                daspect(app.UIAxisCourse,[1 1 1]); % x,y軸の縦横比を1:1にする

                % コース軸の設定
                % カラーバー表示
                c = colorbar(app.UIAxisCourse);
                c.Label.String = 'velocity[mm/s]';
                c.Label.FontSize = 12;
                app.courseColorbar = c; % 新規作成したカラーバーのハンドルを保持

                % ショートカット軌跡表示ボタンがONの時
                if app.ShortCutplotSwitch.Value == "On"
                    hold(app.UIAxisCourse, 'on');
                    scatter(app.UIAxisCourse,app.shortCutCourse.x,app.shortCutCourse.y);
                    hold(app.UIAxisCourse, 'off');
                end
                
            catch ME
                app.Lamp.Color = "red";     % ステータスランプ赤
                % エラー表示
                for num = 1:1:length(ME.stack)
                    % setTexttarea(app, {ME.stack(num,1).name,num2str(ME.stack(num,1).line)})
                end
                % setTexttarea(app, {ME.identifier,ME.message})
            end
        end

        % 定数線の表示
        function setXlines(app,time)
            % 定数線
            delete(app.xlines{1});
            delete(app.xlines{2});
            delete(app.xlines{3});
            delete(app.xlines{4});
            app.xlines{1} = xline(app.UIAxes1,time);
            app.xlines{2} = xline(app.UIAxes2,time);
            app.xlines{3} = xline(app.UIAxes3,time);
            app.xlines{4} = xline(app.UIAxesAnalysis,time);
        end
        
        % ログの取得、再プロット
        function reload(app,EventName)
            if app.rootDir == 0
                app.rootDir = uigetdir;
            end
            if app.rootDir ~= 0
                nowLognum = app.LogDropDown.Value;

                if max(strcmp(EventName,"ButtonPushed")) == 1
                    getLogfiles(app);   % ログファイル一覧を取得
                end
                app.LogDropDown.Value = nowLognum;
                getLogdata(app);    % ログデータを取得する
                getLogvalues(app);  % ログ変数一覧を取得


                % プロット初期化
                cla(app.UIAxisCourse)
                cla(app.UIAxes1)
                cla(app.UIAxes2)
                cla(app.UIAxes3)
                cla(app.UIAxesAnalysis)
    
                % 変数プロットのx軸設定
                app.UIAxes1.XLim = [0 app.time(end,1)];
                app.UIAxes2.XLim = [0 app.time(end,1)];
                app.UIAxes3.XLim = [0 app.time(end,1)];
                app.UIAxesAnalysis.XLim = [0 app.time(end,1)];
    
                % スライダー範囲の設定
                app.TimesSlider.Limits = [1 numel(app.time)];   % 時間配列のインデックスをスライダーの範囲に設定
                Ticks = round(linspace(1,numel(app.time),17));  % インデックスを17等分する
                app.TimesSlider.MajorTicks = Ticks;             % 主目盛りを設定
                TicksLabelnum = app.time(Ticks');               % ラベルにする秒数を時間配列から取得
                TicksLabelstr = num2str(TicksLabelnum);         % 秒数を文字列に変換
                TicksLabel = cellstr(TicksLabelstr)';           % cell配列に変換
                app.TimesSlider.MajorTickLabels = TicksLabel;   % ラベルを設定
    
                % 変数初期化
                % app.kgyro = 1;
                % app.CourseajustgyroKnob.Value = 1;
                % app.Spinner.Value = 1;
    
                % コース座標計算
                calcCourseplot(app);    % コース座標計算
                
                % コース描画
                plotCourse(app)
    
                % 変数プロット
                plotVariableGraphs(app);

                % 変数解析プロット
                procData(app,"left");
                procData(app,"right");

                % 選択フラグのプロット
                plotFlagPoint(app);
            end
        end
        
        % 設定用変数の保存
        function saveVars(app)
            % 設定をCSVファイルに書き出す
			settings = {
                "logFolderDir", app.rootDir;
				"timeEquation", app.timeEditField.Value;
				"velocityEquation", app.velocityEditField.Value;
				"angularVelocityEquation", app.angularVelocityEditField.Value;
				"radsCheckBox", string(app.radsCheckBox.Value);
				"switchDirection", app.SwitchDirection.Value;
				"data1", string(app.Data1DropDown.Value);
				"data2", string(app.Data2DropDown.Value);
				"data3", string(app.Data3DropDown.Value);
				"analysisLeft", string(app.DataAnalysisLDropDown.Value);
				"analysisRight", string(app.DataAnalysisRDropDown.Value);
				"smoothLeft", string(app.smoothLDropDown.Value);
				"smoothRight", string(app.smoothRDropDown.Value);
				"windowLeft", string(app.windowLSpinner.Value);
				"windowRight", string(app.windowRSpinner.Value);
				"normalizeLeft", string(app.normalizeLSwitch.Value);
				"normalizeRight", string(app.normalizeRSwitch.Value);
				"detrendLeft", string(app.detrendLSwitch.Value);
				"detrendRight", string(app.detrendRSwitch.Value);
				"ylimMatch", string(app.ylimmatchSwitch.Value);
				"plotSwitch", string(app.ShortCutplotSwitch.Value);
				"windowSlider", string(app.ShortCutwindowSlider.Value)
			};
			settingsTable = cell2table(settings,"VariableNames",{'key','value'}); % table作成
            folderPath = "";
            if app.csvFilePathEditer.Value == ""
                F = msgbox("設定ファイルの保存フォルダを選択してください");
                folderPath = uigetdir; % パスがないときはフォルダーを選択する
                if any(folderPath)
                    app.csvFilePathEditer.Value = folderPath;
                end
                close(F);
            end
            if any(app.csvFilePathEditer.Value) || any(folderPath)
                filePath = append(app.csvFilePathEditer.Value,"\",app.csvFileName);
			    writetable(settingsTable,filePath);
                setTexttarea(app, "設定保存完了")
            end
        end
        
        % データ処理
        function procData(app,pos)
            % 設定項目を取得
            if strcmp(pos,"left") == 1
                varName = app.DataAnalysisLDropDown.Value;  % 変数名取得
                method = app.smoothLDropDown.Value; % 平滑化メソッドを取得
                window = app.windowLSpinner.Value;  % 移動ウィンドウ数
                normalizeState = app.normalizeLSwitch.Value;    % 正規化の有無
                detrendState = app.detrendLSwitch.Value;        % トレンド除去の有無
            elseif strcmp(pos,"right") == 1
                varName = app.DataAnalysisRDropDown.Value;  % 変数名取得
                method = app.smoothRDropDown.Value; % 平滑化メソッドを取得
                window = app.windowRSpinner.Value;  % 移動ウィンドウ数
                normalizeState = app.normalizeRSwitch.Value;    % 正規化の有無
                detrendState = app.detrendRSwitch.Value;        % トレンド除去の有無
            end
            match = app.ylimmatchSwitch.Value;    % Y軸範囲一致設定を取得

            yyaxis(app.UIAxesAnalysis,pos)      % 軸の左右を選択
            ylabel(app.UIAxesAnalysis,varName); % y軸ラベルを表示
            if strcmp(varName,"none") == 0  % 選択Dataがnone以外
                varData = app.log.(varName); % ログデータを取得
                
                % データの平滑化
                if strcmp(method,"none") == 0 % smoothがnone以外
                    varData = smoothdata(varData,method,window); 
                end
                % データの正規化
                if strcmp(normalizeState,"On") == 1
                    varData = normalize(varData); 
                end
                % データのトレンド除去
                if strcmp(detrendState,"On") == 1
                    varData = detrend(varData); 
                end

                plot(app.UIAxesAnalysis,app.time,varData) 
                index = round(app.TimesSlider.Value);
                setXlines(app,app.time(index)); %  X定数線を更新
                
                varNameL = app.DataAnalysisLDropDown.Value;  % 左側変数名取得
                varNameR = app.DataAnalysisRDropDown.Value;  % 右側変数名取得
                if strcmp(match,"On") == 1 && strcmp(varNameL,"none") == 0 && strcmp(varNameR,"none") == 0
                    % 片方がnoneのときはY軸を揃えない
                    % Y軸の範囲設定を左右で一致させる
                    varDataL = app.log.(varNameL); % 左側ログデータを取得
                    varDataR = app.log.(varNameR); % 右側ログデータを取得
                    varData = [varDataL varDataR]; % Y軸範囲を取得

                    % 上限を最大値、下限を最小値にする
                    limmin = min(min(varData));
                    limmax = max(max(varData));

                    if limmin >= 0
                        limmin = limmin * 0.9;
                    else
                        limmin = limmin * 1.1;
                    end

                    if limmax >= 0
                        limmax = limmax * 1.1;
                    else
                        limmax = limmax * 0.9;
                    end
                    yyaxis(app.UIAxesAnalysis,"left")
                    app.UIAxesAnalysis.YLim = [limmin limmax];
                    yyaxis(app.UIAxesAnalysis,"right")
                    app.UIAxesAnalysis.YLim = [limmin limmax];
                else
                    % Y軸範囲をAuto変更にする
                    yyaxis(app.UIAxesAnalysis,"left")
                    app.UIAxesAnalysis.YLimMode = "auto";
                    yyaxis(app.UIAxesAnalysis,"right")
                    app.UIAxesAnalysis.YLimMode = "auto";
                end
            else
                cla(app.UIAxesAnalysis) % none選択時はグラフ消去    
            end
        end
        
        function getTimeIndex(app,~,event)           
            % グラフからデータを取り出す
            X = event.Source.XData;
            Y = event.Source.YData;

            % マウス座標を取得
            if class(event.Source) == "matlab.graphics.chart.primitive.Scatter"     % コース描画
                Cp = get(app.UIAxisCourse,'CurrentPoint');
            elseif class(event.Source) == "matlab.graphics.chart.primitive.Line"    % 変数グラフ
                % どのグラフかを判別する
                if strcmp(event.Source.Parent.Title.String,(app.Data1DropDown.Value))
                    Cp = get(app.UIAxes1,'CurrentPoint');
                elseif strcmp(event.Source.Parent.Title.String,(app.Data2DropDown.Value))
                    Cp = get(app.UIAxes2,'CurrentPoint');
                elseif strcmp(event.Source.Parent.Title.String,(app.Data3DropDown.Value))
                    Cp = get(app.UIAxes3,'CurrentPoint');
                end
            end      

            % クリック位置のXy座標
            Xp = Cp(2,1);
            Yp = Cp(2,2);

            % クリック点に近い配列値のインデックス
            if class(event.Source) == "matlab.graphics.chart.primitive.Scatter"     % コース描画
                [~,Ip] = min((X-Xp).^2+(Y-Yp).^2);
            elseif class(event.Source) == "matlab.graphics.chart.primitive.Line"    % 変数グラフ
                [~,Ip] = min((X-Xp).^2);
            end
            timeIndex = app.course.Time(Ip);        % クリック点での時間を取得

            setXlines(app,seconds(timeIndex));      % 定数線更新
            app.TimesSlider.Value = Ip;  % スライドバー更新

            % コース上に現在地をプロット
            delete(app.marker)          % 前回値を削除
            hold(app.UIAxisCourse, 'on');
            app.marker = scatter(app.UIAxisCourse,app.course{timeIndex,'x'},app.course{timeIndex,'y'},"m",'filled');
            hold(app.UIAxisCourse, 'off');           
        end


        function plotVariableGraphs(app)
            % 変数プロット
            title(app.UIAxes1, app.Data1DropDown.Value)
            title(app.UIAxes2, app.Data2DropDown.Value)
            title(app.UIAxes3, app.Data3DropDown.Value)
            app.UIAxes1.Title.Visible = "off";
            app.UIAxes2.Title.Visible = "off";
            app.UIAxes3.Title.Visible = "off";

            if app.graphPositionBotton.Value == "On"
                plot(app.UIAxes1,app.time,app.log.(app.Data1DropDown.Value),'ButtonDownFcn',@(src,evnt)getTimeIndex(app,src,evnt))
                plot(app.UIAxes2,app.time,app.log.(app.Data2DropDown.Value),'ButtonDownFcn',@(src,evnt)getTimeIndex(app,src,evnt))
                plot(app.UIAxes3,app.time,app.log.(app.Data3DropDown.Value),'ButtonDownFcn',@(src,evnt)getTimeIndex(app,src,evnt))
            else
                plot(app.UIAxes1,app.time,app.log.(app.Data1DropDown.Value))
                plot(app.UIAxes2,app.time,app.log.(app.Data2DropDown.Value))
                plot(app.UIAxes3,app.time,app.log.(app.Data3DropDown.Value))
            end

            linkaxes([app.UIAxes1 app.UIAxes2 app.UIAxes3],'x')
            index = round(app.TimesSlider.Value);
            setXlines(app,app.time(index));

            % procData(app,"right");
            % procData(app,"left");
        end
        
        function getParameter(app)
            
        end
        
        function plotFlagPoint(app)
            enable1 = app.Plotflag1enableCheckBox.Value;
            enable2 = app.Plotflag2enableCheckBox.Value;
            enable3 = app.Plotflag3enableCheckBox.Value;
            if enable1
                % コース上に現在地をプロット
                delete(app.flag1)          % 前回値を削除
                hold(app.UIAxisCourse, 'on');
                raise = app.log.(app.Plotflag1DropDown.Value) > 0;
                snapTime = app.course.Time(raise);
                flag1x = app.course{snapTime,'x'};
                flag1y = app.course{snapTime,'y'};
                app.flag1 = scatter(app.UIAxisCourse,flag1x,flag1y,"red",'filled');
                hold(app.UIAxisCourse, 'off');
            else
                delete(app.flag1)          % 前回値を削除
            end

            if enable2
                % コース上に現在地をプロット
                delete(app.flag2)          % 前回値を削除
                hold(app.UIAxisCourse, 'on');
                raise = app.log.(app.Plotflag2DropDown.Value) > 0;
                snapTime = app.course.Time(raise);
                flag2x = app.course{snapTime,'x'};
                flag2y = app.course{snapTime,'y'};
                app.flag2 = scatter(app.UIAxisCourse,flag2x,flag2y,"green",'filled');
                hold(app.UIAxisCourse, 'off');
            else
                delete(app.flag2)          % 前回値を削除
            end

            if enable3
                % コース上に現在地をプロット
                delete(app.flag3)          % 前回値を削除
                hold(app.UIAxisCourse, 'on');
                raise = app.log.(app.Plotflag3DropDown.Value) > 0;
                snapTime = app.course.Time(raise);
                flag3x = app.course{snapTime,'x'};
                flag3y = app.course{snapTime,'y'};
                app.flag3 = scatter(app.UIAxisCourse,flag3x,flag3y,"blue",'filled');
                hold(app.UIAxisCourse, 'off');
            else
                delete(app.flag3)          % 前回値を削除
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            % 起動時
            try
                % 設定ファイルを選択する
                F = msgbox("設定ファイルを選択してください");
                [filePath,location] = uigetfile({'*.csv'});
                close(F);
                % 設定用変数ファイルの読み込み（CSV）
                if filePath ~= 0
                    filePath = append(location,filePath);
                    settingsTable = readtable(filePath,"TextType","string");   % Table型に変換
                    settingsMap = containers.Map(settingsTable.key, settingsTable.value);   % キーマップ作成
                    
                    % 換算式をCSVから復元
                    app.timeEditField.Value = settingsMap("timeEquation");
                    app.velocityEditField.Value = settingsMap("velocityEquation");
                    app.angularVelocityEditField.Value = settingsMap("angularVelocityEquation");
                    app.radsCheckBox.Value = lower(settingsMap("radsCheckBox")) == "true";
                    
                    % 解析・ショートカット設定をCSVから復元
                    app.SwitchDirection.Value = settingsMap("switchDirection");
                    app.ShortCutplotSwitch.Value = settingsMap("plotSwitch");
                    app.ylimmatchSwitch.Value = settingsMap("ylimMatch");
                    app.normalizeLSwitch.Value = settingsMap("normalizeLeft");
                    app.normalizeRSwitch.Value = settingsMap("normalizeRight");
                    app.detrendLSwitch.Value = settingsMap("detrendLeft");
                    app.detrendRSwitch.Value = settingsMap("detrendRight");
                    
                    % スピナー／スライダーの数値項目を文字列から復元
                    windowLeft = str2double(settingsMap("windowLeft"));
                    if ~isnan(windowLeft)
                        app.windowLSpinner.Value = windowLeft;
                    end
                    windowRight = str2double(settingsMap("windowRight"));
                    if ~isnan(windowRight)
                        app.windowRSpinner.Value = windowRight;
                    end
                    sliderWindow = str2double(settingsMap("windowSlider"));
                    if ~isnan(sliderWindow)
                        app.ShortCutwindowSlider.Value = sliderWindow;
                    end
                    
                    % ログフォルダパスをCSVから復元
                    app.rootDir = settingsMap("logFolderDir");
                    str = strsplit(app.rootDir, filesep);
                    app.FolderButton.Text = str{end};

                    % 設定ファイルのパスをCSVから復元
                    app.csvFilePathEditer.Value = location;
                else
                    % 設定ファイルが無いときはログフォルダを選択する
                    F = msgbox("ログファイルの保存フォルダを選択してください");
                    app.rootDir = uigetdir;
                    close(F);
                end
            catch ME
                if (strcmp(ME.identifier,'MATLAB:load:couldNotReadFile'))
                    % 設定用変数ファイルが無い
                end
            end

            if app.rootDir ~= 0
		        getLogfiles(app);   % ログファイル一覧を取得
		        getLogdata(app);    % ログデータを取得する
		        getLogvalues(app);  % ログ変数一覧を取得
    
		        % 前回の表示グラフ名を取得
		        if filePath ~= 0
			        app.Data1DropDown.Value = settingsMap("data1");
			        app.Data2DropDown.Value = settingsMap("data2");
			        app.Data3DropDown.Value = settingsMap("data3");
                    
			        if isKey(settingsMap,"analysisLeft") && ismember(settingsMap("analysisLeft"), string(app.DataAnalysisLDropDown.Items))
				        app.DataAnalysisLDropDown.Value = settingsMap("analysisLeft");
			        end
			        if isKey(settingsMap,"analysisRight") && ismember(settingsMap("analysisRight"), string(app.DataAnalysisRDropDown.Items))
				        app.DataAnalysisRDropDown.Value = settingsMap("analysisRight");
			        end
			        if isKey(settingsMap,"smoothLeft")
				        app.smoothLDropDown.Value = settingsMap("smoothLeft");
			        end
			        if isKey(settingsMap,"smoothRight")
				        app.smoothRDropDown.Value = settingsMap("smoothRight");
			        end
		        end
        
		        % スライダー範囲の設定
                app.TimesSlider.Limits = [1 numel(app.time)];   % 時間配列のインデックスをスライダーの範囲に設定
                Ticks = round(linspace(1,numel(app.time),17));  % インデックスを17等分する
                app.TimesSlider.MajorTicks = Ticks;             % 主目盛りを設定
                TicksLabelnum = app.time(Ticks');               % ラベルにする秒数を時間配列から取得
                TicksLabelstr = num2str(TicksLabelnum);         % 秒数を文字列に変換
                TicksLabel = cellstr(TicksLabelstr)';           % cell配列に変換
                app.TimesSlider.MajorTickLabels = TicksLabel;   % ラベルを設定

		        % 変数プロットのx軸設定
		        app.UIAxes1.XLim = [0 app.time(end,1)];
		        app.UIAxes2.XLim = [0 app.time(end,1)];
		        app.UIAxes3.XLim = [0 app.time(end,1)];
		        app.UIAxesAnalysis.XLim = [0 app.time(end,1)];
        
		        % コース軸の設定
		        % カラーバー表示
		        if ~isempty(app.courseColorbar) && isvalid(app.courseColorbar)
			        delete(app.courseColorbar); % 既存カラーバーを削除して重複を回避
		        end
		        c = colorbar(app.UIAxisCourse);
		        c.Label.String = 'velocity[mm/s]';
		        c.Label.FontSize = 12;
		        app.courseColorbar = c; % 新規作成したカラーバーのハンドルを保持
        
		        % 軸設定
		        if strcmp(app.SwitchDirection.Value,'CW') == 1
			        app.UIAxisCourse.XLim = [app.courseSize(1,1) app.courseSize(1,2)];
			        app.UIAxisCourse.YLim = [app.courseSize(2,1) app.courseSize(2,2)];
		        else
			        app.UIAxisCourse.XLim = [-app.courseSize(1,2) -app.courseSize(1,1)];
			        app.UIAxisCourse.YLim = [app.courseSize(2,1) app.courseSize(2,2)];
		        end
		        app.UIAxisCourse.XTick = -10000:100:10000;
		        app.UIAxisCourse.YTick = -10000:100:10000;
        
		        % コース描画、変数グラフ表示
		        % コース座標計算
		        calcCourseplot(app);    % コース座標計算
        
		        % コース描画
		        plotCourse(app)
        
		        % 変数プロット
		        plotVariableGraphs(app);

                % 変数解析プロット
                procData(app,"left");
                procData(app,"right");
        
		        % 単位変更
                if app.radsCheckBox.Value == 0
                    app.angularVelocitydegsLabel.Text = 'angularVelocity [deg/s] = ';
                elseif app.radsCheckBox.Value == 1
                    app.angularVelocitydegsLabel.Text = 'angularVelocity [rad/s] = ';
                end
            end
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            % フォルダー選択
            app.rootDir = uigetdir;
            if app.rootDir ~= 0
                str = strsplit(app.rootDir,'\');
                app.FolderButton.Text = str{end};
                getLogfiles(app);       % ログファイル一覧を取得
            end
        end

        % Value changed function: LogDropDown
        function LogDropDownValueChanged(app, event)
            reload(app,event.EventName);
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            reload(app,event.EventName);
            saveVars(app);
        end

        % Value changing function: TimesSlider
        function TimesSliderValueChanging(app, event)
            if isprop(event,"Value")
                changingValue = event.Value;
            else
                changingValue = event;
            end
            
            index = round(changingValue);
            t = min(seconds(app.time(index)));
            snapTime = app.course.Time(t);

            % コース上に現在地をプロット
            delete(app.marker)          % 前回値を削除
            hold(app.UIAxisCourse, 'on');
            app.marker = scatter(app.UIAxisCourse,app.course{snapTime,'x'},app.course{snapTime,'y'},"m",'filled');
            hold(app.UIAxisCourse, 'off');
            % 変数グラフ上にプロット
            setXlines(app,app.time(index));
            
        end

        % Value changed function: Data1DropDown
        function Data1DropDownValueChanged(app, event)
            value = app.Data1DropDown.Value;
            plot(app.UIAxes1,app.time,app.log.(value))
            index = round(app.TimesSlider.Value);
            setXlines(app,app.time(index)); %  X定数線を更新
            title(app.UIAxes1, value)   % タイトル
            app.UIAxes1.Title.Visible = "off";
        end

        % Value changed function: Data2DropDown
        function Data2DropDownValueChanged(app, event)
            value = app.Data2DropDown.Value;
            plot(app.UIAxes2,app.time,app.log.(value))
            index = round(app.TimesSlider.Value);
            setXlines(app,app.time(index)); %  X定数線を更新
            title(app.UIAxes2, value)   % タイトル
            app.UIAxes2.Title.Visible = "off";
        end

        % Value changed function: Data3DropDown
        function Data3DropDownValueChanged(app, event)
            value = app.Data3DropDown.Value;
            plot(app.UIAxes3,app.time,app.log.(value))
            index = round(app.TimesSlider.Value);
            setXlines(app,app.time(index)); %  X定数線を更新
            title(app.UIAxes3, value)   % タイトル
            app.UIAxes3.Title.Visible = "off";
        end

        % Value changed function: DataAnalysisLDropDown
        function DataAnalysisLDropDownValueChanged(app, event)
            procData(app,"left");
        end

        % Value changed function: DataAnalysisRDropDown
        function DataAnalysisRDropDownValueChanged(app, event)
            procData(app,"right");
        end

        % Value changed function: smoothLDropDown
        function smoothLDropDownValueChanged(app, event)
            procData(app,"left");
        end

        % Value changed function: smoothRDropDown
        function smoothRDropDownValueChanged(app, event)
            procData(app,"right");
        end

        % Value changed function: windowLSpinner
        function windowLSpinnerValueChanged(app, event)
            procData(app,"left");
        end

        % Value changed function: windowRSpinner
        function windowRSpinnerValueChanged(app, event)
            procData(app,"right");
        end

        % Value changed function: normalizeLSwitch
        function normalizeLSwitchValueChanged(app, event)
            procData(app,"left");
        end

        % Value changed function: normalizeRSwitch
        function normalizeRSwitchValueChanged(app, event)
            procData(app,"right");
        end

        % Value changed function: detrendLSwitch
        function detrendLSwitchValueChanged(app, event)
            procData(app,"left");
        end

        % Value changed function: detrendRSwitch
        function detrendRSwitchValueChanged(app, event)
            procData(app,"right");
        end

        % Value changed function: ylimmatchSwitch
        function ylimmatchSwitchValueChanged(app, event)
            procData(app,"right");
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.CourseajustgyroKnob.Value = changingValue;
            
            app.kgyro = changingValue; % 角度補正係数にノブの値を代入
            calcCourseplot(app);    % コース座標計算
            % コース描画
            plotCourse(app)
        end

        % Value changing function: CourseajustgyroKnob
        function CourseajustgyroKnobValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner.Value = changingValue;
            
            app.kgyro = changingValue; % 角度補正係数にノブの値を代入
            calcCourseplot(app);    % コース座標計算
            % コース描画
            plotCourse(app)
        end

        % Value changed function: SwitchDirection
        function SwitchDirectionValueChanged(app, event)
            if strcmp(app.SwitchDirection.Value,'CW') == 1
                app.UIAxisCourse.XLim = [app.courseSize(1,1) app.courseSize(1,2)];
                app.UIAxisCourse.YLim = [app.courseSize(2,1) app.courseSize(2,2)];
            else
                app.UIAxisCourse.XLim = [-app.courseSize(1,2) -app.courseSize(1,1)];
                app.UIAxisCourse.YLim = [-app.courseSize(2,2) -app.courseSize(2,1)];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            saveVars(app); % 変数保存
            delete(app)
        end

        % Value changed function: radsCheckBox
        function radsCheckBoxValueChanged(app, event)
            value = app.radsCheckBox.Value;
            if value == 0
                app.angularVelocitydegsLabel.Text = 'angularVelocity [deg/s] = ';
            elseif value == 1
                app.angularVelocitydegsLabel.Text = 'angularVelocity [rad/s] = ';
            end
        end

        % Value changed function: graphPositionBotton
        function graphPositionBottonValueChanged(app, event)
            value = app.graphPositionBotton.Value;
            disp(value)
            plotVariableGraphs(app);
        end

        % Value changed function: ShortCutwindowSlider
        function ShortCutwindowSliderValueChanged(app, event)
            % windowSliderの値が1未満にならないよう補正
            if app.ShortCutwindowSlider.Value < 1
                app.ShortCutwindowSlider.Value = 1;
            end
            calcShortCutplot(app);
            plotCourse(app);
        end

        % Value changed function: ShortCutplotSwitch
        function ShortCutplotSwitchValueChanged(app, event)
            plotCourse(app);
        end

        % Button pushed function: Button_Rotate
        function Button_RotatePushed(app, event)
            app.rotate = app.rotate - 90;
            calcCourseplot(app);
            plotCourse(app);
        end

        % Button pushed function: Button_Expand
        function Button_ExpandPushed(app, event)
            if app.expand == 1
                app.expand = 0;

                app.TabGroup.Visible = "on";
                app.CourseajustgyroKnob.Visible = "on";
                app.Spinner.Visible = "on";
                app.SwitchDirection.Visible = "on";

                app.UIAxisCourse.Position = [1 45 661 608];
            else
                app.expand = 1;

                app.TabGroup.Visible = "off";
                app.CourseajustgyroKnob.Visible = "off";
                app.Spinner.Visible = "off";
                app.SwitchDirection.Visible = "off";

                app.UIAxisCourse.Position = [1 45 1162 608];
            end
        end

        % Button pushed function: csvFilePathButton
        function csvFilePathButtonPushed(app, event)
            fileDir = uigetdir;
            if fileDir ~= 0
                app.csvFilePathEditer.Value = fileDir;
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            value = app.TimesSlider.Value; % get the slider value
	        key = event.Key; % get the pressed key value
            if strcmp(key,'leftarrow')
    	        value = value-1; % left value
            elseif strcmp(key,'rightarrow')
                value = value+1; % right value
            end
            app.TimesSlider.Value = value; % set the slider value
	        TimesSliderValueChanging(app, value); % execute the slider callback
        end

        % Value changed function: Plotflag1enableCheckBox
        function Plotflag1enableCheckBoxValueChanged(app, event)
            plotFlagPoint(app); %　フラグをプロット
        end

        % Value changed function: Plotflag2enableCheckBox
        function Plotflag2enableCheckBoxValueChanged(app, event)
            plotFlagPoint(app); %　フラグをプロット
        end

        % Value changed function: Plotflag3enableCheckBox
        function Plotflag3enableCheckBoxValueChanged(app, event)
            plotFlagPoint(app); %　フラグをプロット
        end

        % Value changed function: Plotflag1DropDown
        function Plotflag1DropDownValueChanged(app, event)
            plotFlagPoint(app); %　フラグをプロット
        end

        % Value changed function: Plotflag2DropDown
        function Plotflag2DropDownValueChanged(app, event)
            plotFlagPoint(app); %　フラグをプロット
        end

        % Value changed function: Plotflag3DropDown
        function Plotflag3DropDownValueChanged(app, event)
            plotFlagPoint(app); %　フラグをプロット
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxisCourse = uiaxes(app.UIFigure);
            xlabel(app.UIAxisCourse, 'X[mm]')
            ylabel(app.UIAxisCourse, 'Y[mm]')
            zlabel(app.UIAxisCourse, 'Z[mm]')
            app.UIAxisCourse.XLim = [0 1];
            app.UIAxisCourse.YLim = [0 1];
            app.Lamp = uilamp(app.UIFigure);
            app.FolderButton = uibutton(app.UIFigure, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'path';
            app.FolderpathLabel = uilabel(app.UIFigure);
            app.FolderpathLabel.Text = 'Folder path';
            app.ReloadButton = uibutton(app.UIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.TimesLabel = uilabel(app.UIFigure);
            app.TimesLabel.Text = 'Time [s]';
            app.TimesSlider = uislider(app.UIFigure);
            app.TimesSlider.ValueChangingFcn = createCallbackFcn(app, @TimesSliderValueChanging, true);
            app.LogDropDownLabel = uilabel(app.UIFigure);
            app.LogDropDownLabel.Text = 'Log';
            app.LogDropDown = uidropdown(app.UIFigure);
            app.LogDropDown.Items = {'option'};
            app.LogDropDown.ValueChangedFcn = createCallbackFcn(app, @LogDropDownValueChanged, true);
            app.LogDropDown.Value = 'option';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes3 = uiaxes(app.GraphsTab);
            xlabel(app.UIAxes3, 'Time [s]')
            app.UIAxes2 = uiaxes(app.GraphsTab);
            xlabel(app.UIAxes2, 'Time [s]')
            app.UIAxes1 = uiaxes(app.GraphsTab);
            xlabel(app.UIAxes1, 'Time [s]')
            app.Data2DropDownLabel = uilabel(app.GraphsTab);
            app.Data2DropDownLabel.Text = 'Data2';
            app.Data2DropDown = uidropdown(app.GraphsTab);
            app.Data2DropDown.ValueChangedFcn = createCallbackFcn(app, @Data2DropDownValueChanged, true);
            app.Data3DropDownLabel = uilabel(app.GraphsTab);
            app.Data3DropDownLabel.Text = 'Data3';
            app.Data3DropDown = uidropdown(app.GraphsTab);
            app.Data3DropDown.ValueChangedFcn = createCallbackFcn(app, @Data3DropDownValueChanged, true);
            app.Data1DropDownLabel = uilabel(app.GraphsTab);
            app.Data1DropDownLabel.Text = 'Data1';
            app.Data1DropDown = uidropdown(app.GraphsTab);
            app.Data1DropDown.ValueChangedFcn = createCallbackFcn(app, @Data1DropDownValueChanged, true);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.UIAxesAnalysis = uiaxes(app.AnalysisTab);
            xlabel(app.UIAxesAnalysis, 'Time [s]')
            app.DataLabel = uilabel(app.AnalysisTab);
            app.DataLabel.Text = 'Data ';
            app.DataDropDownLabel_2 = uilabel(app.AnalysisTab);
            app.DataDropDownLabel_2.Text = 'Data';
            app.smoothDropDown_2Label = uilabel(app.AnalysisTab);
            app.smoothDropDown_2Label.Text = 'smooth';
            app.windowSpinner_2Label = uilabel(app.AnalysisTab);
            app.windowSpinner_2Label.Text = 'window';
            app.windowRSpinner = uispinner(app.AnalysisTab);
            app.windowRSpinner.Limits = [2 Inf];
            app.windowRSpinner.ValueChangedFcn = createCallbackFcn(app, @windowRSpinnerValueChanged, true);
            app.windowRSpinner.Value = 2;
            app.smoothRDropDown = uidropdown(app.AnalysisTab);
            app.smoothRDropDown.Items = {'none', 'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.smoothRDropDown.ValueChangedFcn = createCallbackFcn(app, @smoothRDropDownValueChanged, true);
            app.smoothRDropDown.Value = 'none';
            app.DataAnalysisRDropDown = uidropdown(app.AnalysisTab);
            app.DataAnalysisRDropDown.ValueChangedFcn = createCallbackFcn(app, @DataAnalysisRDropDownValueChanged, true);
            app.windowSpinnerLabel = uilabel(app.AnalysisTab);
            app.windowSpinnerLabel.Text = 'window';
            app.windowLSpinner = uispinner(app.AnalysisTab);
            app.windowLSpinner.Limits = [2 Inf];
            app.windowLSpinner.ValueChangedFcn = createCallbackFcn(app, @windowLSpinnerValueChanged, true);
            app.windowLSpinner.Value = 2;
            app.FunctionLabel = uilabel(app.AnalysisTab);
            app.FunctionLabel.Text = 'smooth';
            app.smoothLDropDown = uidropdown(app.AnalysisTab);
            app.smoothLDropDown.Items = {'none', 'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.smoothLDropDown.ValueChangedFcn = createCallbackFcn(app, @smoothLDropDownValueChanged, true);
            app.smoothLDropDown.Value = 'none';
            app.DataAnalysisLDropDown = uidropdown(app.AnalysisTab);
            app.DataAnalysisLDropDown.ValueChangedFcn = createCallbackFcn(app, @DataAnalysisLDropDownValueChanged, true);
            app.normalizeSwitchLabel = uilabel(app.AnalysisTab);
            app.normalizeSwitchLabel.Text = 'normalize';
            app.normalizeLSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.normalizeLSwitch.ValueChangedFcn = createCallbackFcn(app, @normalizeLSwitchValueChanged, true);
            app.detrendSwitchLabel = uilabel(app.AnalysisTab);
            app.detrendSwitchLabel.Text = 'detrend';
            app.detrendLSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.detrendLSwitch.ValueChangedFcn = createCallbackFcn(app, @detrendLSwitchValueChanged, true);
            app.normalizeSwitch_2Label = uilabel(app.AnalysisTab);
            app.normalizeSwitch_2Label.Text = 'normalize';
            app.normalizeRSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.normalizeRSwitch.ValueChangedFcn = createCallbackFcn(app, @normalizeRSwitchValueChanged, true);
            app.detrendSwitch_2Label = uilabel(app.AnalysisTab);
            app.detrendSwitch_2Label.Text = 'detrend';
            app.detrendRSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.detrendRSwitch.ValueChangedFcn = createCallbackFcn(app, @detrendRSwitchValueChanged, true);
            app.ylimmatchSwitchLabel = uilabel(app.AnalysisTab);
            app.ylimmatchSwitchLabel.Text = 'ylim match';
            app.ylimmatchSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.ylimmatchSwitch.ValueChangedFcn = createCallbackFcn(app, @ylimmatchSwitchValueChanged, true);
            app.ParameterTab = uitab(app.TabGroup);
            app.ParameterTab.Title = 'Parameter';
            app.TextArea_2 = uitextarea(app.ParameterTab);
            app.consoleTab = uitab(app.TabGroup);
            app.consoleTab.Title = 'console';
            app.TextArea = uitextarea(app.consoleTab);
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.velocitymmsLabel = uilabel(app.SettingTab);
            app.velocitymmsLabel.Text = 'velocity [mm/s] = ';
            app.velocityEditField = uieditfield(app.SettingTab, 'text');
            app.velocityEditField.Value = 'encCurrentN / 60.074 * 1000';
            app.angularVelocitydegsLabel = uilabel(app.SettingTab);
            app.angularVelocitydegsLabel.Text = 'angularVelocity [deg/s] = ';
            app.angularVelocityEditField = uieditfield(app.SettingTab, 'text');
            app.angularVelocityEditField.Value = 'gyroVal_Z / 10000 * -1';
            app.velocitymmsLabel_2 = uilabel(app.SettingTab);
            app.velocitymmsLabel_2.Text = 'time [s] = ';
            app.timeEditField = uieditfield(app.SettingTab, 'text');
            app.timeEditField.Value = 'cntlog / 1000';
            app.TranslateequationsLabel = uilabel(app.SettingTab);
            app.TranslateequationsLabel.Text = 'Translate equations';
            app.radsCheckBox = uicheckbox(app.SettingTab);
            app.radsCheckBox.ValueChangedFcn = createCallbackFcn(app, @radsCheckBoxValueChanged, true);
            app.radsCheckBox.Text = '[rad/s]';
            app.radsCheckBox.Value = true;
            app.SwitchLabel = uilabel(app.SettingTab);
            app.SwitchLabel.Text = {'Click on the variable graph to display the position on the course'; ''};
            app.graphPositionBotton = uiswitch(app.SettingTab, 'slider');
            app.graphPositionBotton.ValueChangedFcn = createCallbackFcn(app, @graphPositionBottonValueChanged, true);
            app.Spinner = uispinner(app.SettingTab);
            app.Spinner.Step = 0.001;
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.Limits = [0.9 1.4];
            app.Spinner.Value = 1;
            app.CourseajustgyroKnobLabel = uilabel(app.SettingTab);
            app.CourseajustgyroKnobLabel.Text = 'Course ajust (gyro)';
            app.CourseajustgyroKnob = uiknob(app.SettingTab, 'continuous');
            app.CourseajustgyroKnob.Limits = [0.9 1.4];
            app.CourseajustgyroKnob.ValueChangingFcn = createCallbackFcn(app, @CourseajustgyroKnobValueChanging, true);
            app.CourseajustgyroKnob.Value = 1;
            app.SwitchDirection = uiswitch(app.SettingTab, 'toggle');
            app.SwitchDirection.Items = {'CCW', 'CW'};
            app.SwitchDirection.ValueChangedFcn = createCallbackFcn(app, @SwitchDirectionValueChanged, true);
            app.SwitchDirection.Value = 'CW';
            app.Label = uilabel(app.SettingTab);
            app.Label.Text = 'Course Direction';
            app.csvFilePathEditer = uieditfield(app.SettingTab, 'text');
            app.csvFilePathButton = uibutton(app.SettingTab, 'push');
            app.csvFilePathButton.ButtonPushedFcn = createCallbackFcn(app, @csvFilePathButtonPushed, true);
            app.csvFilePathButton.Text = 'path';
            app.SettingCSVfilepathLabel = uilabel(app.SettingTab);
            app.SettingCSVfilepathLabel.Text = 'Setting CSV file path';
            app.ShortCutplotSwitchLabel = uilabel(app.SettingTab);
            app.ShortCutplotSwitchLabel.Text = 'ShortCut plot';
            app.ShortCutplotSwitch = uiswitch(app.SettingTab, 'slider');
            app.ShortCutplotSwitch.ValueChangedFcn = createCallbackFcn(app, @ShortCutplotSwitchValueChanged, true);
            app.ShortCutwindowSlider = uislider(app.SettingTab);
            app.ShortCutwindowSlider.Limits = [1 50];
            app.ShortCutwindowSlider.ValueChangedFcn = createCallbackFcn(app, @ShortCutwindowSliderValueChanged, true);
            app.ShortCutwindowSlider.Value = 1;
            app.ShortCutwindowLabel = uilabel(app.SettingTab);
            app.ShortCutwindowLabel.Text = {'ShortCut'; 'window'};
            app.diffCheckBox = uicheckbox(app.SettingTab);
            app.diffCheckBox.Text = 'diff';
            app.Plotflag1enableCheckBox = uicheckbox(app.SettingTab);
            app.Plotflag1enableCheckBox.ValueChangedFcn = createCallbackFcn(app, @Plotflag1enableCheckBoxValueChanged, true);
            app.Plotflag1enableCheckBox.Text = 'enable';
            app.PlotflagLabel = uilabel(app.SettingTab);
            app.PlotflagLabel.Text = 'Plot flag1';
            app.Plotflag1DropDown = uidropdown(app.SettingTab);
            app.Plotflag1DropDown.ValueChangedFcn = createCallbackFcn(app, @Plotflag1DropDownValueChanged, true);
            app.Plotflag2enableCheckBox = uicheckbox(app.SettingTab);
            app.Plotflag2enableCheckBox.ValueChangedFcn = createCallbackFcn(app, @Plotflag2enableCheckBoxValueChanged, true);
            app.Plotflag2enableCheckBox.Text = 'enable';
            app.Plotflag2DropDownLabel = uilabel(app.SettingTab);
            app.Plotflag2DropDownLabel.Text = 'Plot flag2';
            app.Plotflag2DropDown = uidropdown(app.SettingTab);
            app.Plotflag2DropDown.ValueChangedFcn = createCallbackFcn(app, @Plotflag2DropDownValueChanged, true);
            app.Plotflag3enableCheckBox = uicheckbox(app.SettingTab);
            app.Plotflag3enableCheckBox.ValueChangedFcn = createCallbackFcn(app, @Plotflag3enableCheckBoxValueChanged, true);
            app.Plotflag3enableCheckBox.Text = 'enable';
            app.Plotflag3DropDownLabel = uilabel(app.SettingTab);
            app.Plotflag3DropDownLabel.Text = 'Plot flag3';
            app.Plotflag3DropDown = uidropdown(app.SettingTab);
            app.Plotflag3DropDown.ValueChangedFcn = createCallbackFcn(app, @Plotflag3DropDownValueChanged, true);
            app.Button_Rotate = uibutton(app.UIFigure, 'push');
            app.Button_Rotate.ButtonPushedFcn = createCallbackFcn(app, @Button_RotatePushed, true);
            app.Button_Rotate.Icon = fullfile(pathToMLAPP, 'rotate.jpg');
            app.Button_Rotate.Text = '';
            app.Button_Expand = uibutton(app.UIFigure, 'push');
            app.Button_Expand.ButtonPushedFcn = createCallbackFcn(app, @Button_ExpandPushed, true);
            app.Button_Expand.Icon = fullfile(pathToMLAPP, 'expand.jpg');
            app.Button_Expand.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end